classdef metric_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RangeSpinnerLabel    matlab.ui.control.Label
        RangeSpinner         matlab.ui.control.Spinner
        Cn2SpinnerLabel      matlab.ui.control.Label
        Cn2Spinner           matlab.ui.control.Spinner
        GOButton             matlab.ui.control.Button
        LoadImageButton      matlab.ui.control.Button
        LoadBaselineButton   matlab.ui.control.Button
        PixelEditFieldLabel  matlab.ui.control.Label
        PixelEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        lib_name % Description
        D = 0.095;
        L
        Cn2
        wavelength = 525e-9;
        obj_size
        img_w
        img_h
        img
        metric_sum
        metric_value
        metric_count
        baseline_img
        img_blur
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            full_path = mfilename('fullpath');
            [startpath,  filename, ext] = fileparts(full_path);
            plot_num = 1;
            
            cd(startpath);
            
            clear tempdir
            setenv('TMP',startpath);

            lib_path = '..\turb_sim_lib\build\Release\';
            app.lib_name = 'turb_sim';
            hfile = '../common/include/turb_sim_lib.h';
            
            if(~libisloaded(app.lib_name))
                [notfound, warnings] = loadlibrary(strcat(lib_path, app.lib_name,'.dll'), hfile);
            end
            
            if(~libisloaded(app.lib_name))
               fprintf('\nThe %s library did not load correctly!',  strcat(app.lib_name,'.dll'));    
            end
            
        end

        % Value changed function: Cn2Spinner, RangeSpinner
        function update_turbulence_params(app, event)
            app.L = app.RangeSpinner.Value;
            app.Cn2 = app.Cn2Spinner.Value;
            app.obj_size = app.PixelEditField.Value;

            calllib(app.lib_name, 'init_turbulence_params', app.img_w, app.D, app.L, app.Cn2, app.wavelength, app.obj_size);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            platform = string(getenv("PLATFORM"));
            if(platform == "Laptop")
                data_root = "D:\data\turbulence\";
            elseif (platform == "LaptopN")
                data_root = "C:\Projects\data\turbulence\";
            else   
                data_root = "C:\Data\JSSAP\";
            end
            
            file_filter = {'*.png','Image Files';'*.*','All Files' };

            [data_file, data_path] = uigetfile(file_filter, 'Enter Save File Name', data_root);
            if(data_path == 0)
                app.img = [];
                return;
            end
%             app.startpath = data_path;
            
            app.img = double(imread(fullfile(data_path, data_file)));
            
            app.img_h = size(app.img, 1);
            app.img_w = size(app.img, 2);
            
            if(size(app.img, 3) == 3)
               app.img = app.img(:, :, 2); 
            end
            
        end

        % Button pushed function: LoadBaselineButton
        function LoadBaselineButtonPushed(app, event)
            platform = string(getenv("PLATFORM"));
            if(platform == "Laptop")
                data_root = "D:\data\turbulence\";
            elseif (platform == "LaptopN")
                data_root = "C:\Projects\data\turbulence\";
            else   
                data_root = "C:\Data\JSSAP\";
            end
            
            file_filter = {'*.png','Image Files';'*.*','All Files' };

            [data_file, data_path] = uigetfile(file_filter, 'Enter Save File Name', data_root);
            if(data_path == 0)
                app.img = [];
                return;
            end
%             app.startpath = data_path;
            
            app.baseline_img = double(imread(fullfile(data_path, data_file)));
            
            if(size(app.baseline_img, 3) == 3)
               app.baseline_img = app.baseline_img(:, :, 2); 
            end
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            pwd
            img_blur = zeros(app.img_h * app.img_w, 1);

            % create the correct matlab pointers to pass into the function
            img_t = libpointer('doublePtr', app.baseline_img);
            img_blur_t = libpointer('doublePtr', img_blur);
            
            calllib(app.lib_name, 'apply_turbulence', app.img_w, app.img_h, img_t, img_blur_t);
        
            img_blur = reshape(img_blur_t.Value, [app.img_h, app.img_w])';
            
            combined_img = cat(2, app.img, img_blur);
            
            figure(2)
            image(uint8(combined_img));
            colormap(gray(256));
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            unloadlibrary(app.lib_name);
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RangeSpinnerLabel = uilabel(app.UIFigure);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RangeSpinner = uispinner(app.UIFigure);
            app.RangeSpinner.Step = 50;
            app.RangeSpinner.Limits = [500 1100];
            app.RangeSpinner.ValueChangedFcn = createCallbackFcn(app, @update_turbulence_params, true);
            app.RangeSpinner.Value = 600;
            app.Cn2SpinnerLabel = uilabel(app.UIFigure);
            app.Cn2SpinnerLabel.Text = 'Cn2';
            app.Cn2Spinner = uispinner(app.UIFigure);
            app.Cn2Spinner.Step = 1e-17;
            app.Cn2Spinner.Limits = [1e-17 1e-12];
            app.Cn2Spinner.ValueChangedFcn = createCallbackFcn(app, @update_turbulence_params, true);
            app.Cn2Spinner.Value = 1e-13;
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadBaselineButton = uibutton(app.UIFigure, 'push');
            app.LoadBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBaselineButtonPushed, true);
            app.LoadBaselineButton.Text = 'Load Baseline';
            app.PixelEditFieldLabel = uilabel(app.UIFigure);
            app.PixelEditFieldLabel.Text = 'Pixel';
            app.PixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelEditField.Value = 0.004217;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metric_test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end